classdef RobotInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConnectButton          matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        SpeedEditFieldLabel    matlab.ui.control.Label
        SpeedEditField         matlab.ui.control.NumericEditField
        OutputEditFieldLabel   matlab.ui.control.Label
        OutputEditField        matlab.ui.control.EditField
        ConnectedLampLabel     matlab.ui.control.Label
        ConnectedLamp          matlab.ui.control.Lamp
        StopButton             matlab.ui.control.Button
        MotorRunningLampLabel  matlab.ui.control.Label
        MotorRunningLamp       matlab.ui.control.Lamp
        AgitatorSliderLabel    matlab.ui.control.Label
        AgitatorSlider         matlab.ui.control.Slider
        ReadBarcodeButton      matlab.ui.control.Button
    end


    properties (Access = private)
        lego
        motorL
        motorR
        connection
        colorSensorBar
        motorBar
        barcode = []
        typeMaterial = {}
        typeCount = []
    end


    methods (Access = private)

        % Callback function: AgitatorSlider
        function AgitatorSliderOnChange(app, event)
            value = app.AgitatorSlider.Value;
            app.motorL.Speed = value;
        end

        % Button pushed function: ConnectButton
        function Connect(app, event)
           %throws error no public property for lamp
           %look up how lamps work
           app.ConnectedLamp.Color = 'k';
           app.connection = false;
           
           clearvars -global app.lego
           try
               app.lego = legoev3('usb');
               app.ConnectedLamp.Color = 'g';
               app.connection = true;
               
               app.motorR = motor(app.lego,'A');
               app.motorL = motor(app.lego,'B');
               
               beep(app.lego);
               clearLCD(app.lego);
               app.ConnectButton.Enable = 'off';
           catch ME
               ME
               app.OutputEditField.Value = 'No Ev3 connected in USB port';
           end
           
        end

        % Callback function
        function Disconnect(app, event)
            %button deleted, ingore this function
        end

        % Value changed function: SpeedEditField
        function OnChange(app, event)
            value = app.SpeedEditField.Value;
            if app.connection
                app.motorR.Speed = value;
            end
            app.OutputEditField.Value = ['Speed set to ' num2str(value)];
        end

        % Button pushed function: ReadBarcodeButton
        function ReadBarcode(app, event)
            if app.connection && any(find([app.lego.ConnectedSensors{:}] == 'color') == 1)
                %dont remove this ^^
                
                %make a function here
                app.colorSensorBar = colorSensor(app.lego, 1);
                
                %include try-catch
                try
                    app.motorBar = motor(app.lego,'C');
                    success = 1;
                catch
                    success = 0;
                    app.OutputEditField.Value = 'Barcode motor is not connected to port C';
                end
                
                if success
                    app.barcode = BarcodeFunction(app.motorBar, app.colorSensorBar);
                end
                
                barcodeString = num2str(app.barcode(1:3),'%d');
                
                switch barcodeString
                    case '001'
                        app.typeMaterial = [app.typeMaterial,'White Glass (Big/Small)'];
                    case '010'
                        app.typeMaterial = [app.typeMaterial,'Red Glass (Big/Small)'];
                    case '011'
                        app.typeMaterial = [app.typeMaterial,'Blue Glass (Big/Small)'];
                    case '100'
                        app.typeMaterial = [app.typeMaterial,'Steel/HDPE'];
                    otherwise
                        app.OutputEditField.Value = 'invalid barcode';
                        app.typeMaterial = [app.typeMaterial,'Invalid'];
                end
                
                if ~(any(ismember(app.typeMaterial, 'Invalid')))
                    decimalCount = polyval(app.barcode(4:8),2) - 1;
                    app.typeCount = [app.typeCount mod(decimalCount,4) floor(decimalCount/4)];
                else
                    app.typeCount = [app.typeCount 0 0];
                end
                app.OutputEditField.Value = WordJoiner(app.typeMaterial,app.typeCount);
                
            end
        end

        % Button pushed function: StartButton
        function Start(app, event)
            if app.connection
                app.MotorRunningLamp.Color = 'g';
                
                app.motorR.Speed = app.SpeedEditField.Value;
                app.motorL.Speed = 20;
                
                pause(0.5);
                
                start(app.motorR);
                start(app.motorL);
                
                app.motorL.Speed = app.AgitatorSlider.Value;
            
                app.OutputEditField.Value = 'Started';
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            if app.connection
                app.MotorRunningLamp.Color = 'k';
                
                app.motorR.Speed = 0;
                app.motorL.Speed = 0;
                
                stop(app.motorL);
                stop(app.motorR);
                
                app.OutputEditField.Value = 'Stopped';
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @Connect, true);
            app.ConnectButton.Text = 'Connect';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @OnChange, true);
            app.SpeedEditField.Limits = [0 100];
            app.SpeedEditField.ValueDisplayFormat = '%.0f';
            app.SpeedEditField.Value = 20;
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.Editable = 'off';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.MotorRunningLampLabel = uilabel(app.UIFigure);
            app.MotorRunningLampLabel.Text = 'Motor Running';
            app.MotorRunningLamp = uilamp(app.UIFigure);
            app.AgitatorSliderLabel = uilabel(app.UIFigure);
            app.AgitatorSliderLabel.Text = 'Agitator';
            app.AgitatorSlider = uislider(app.UIFigure);
            app.AgitatorSlider.Limits = [-100 100];
            app.AgitatorSlider.ValueChangedFcn = createCallbackFcn(app, @AgitatorSliderOnChange, true);
            app.AgitatorSlider.ValueChangingFcn = createCallbackFcn(app, @AgitatorSliderOnChange, true);
            app.AgitatorSlider.Value = 100;
            app.ReadBarcodeButton = uibutton(app.UIFigure, 'push');
            app.ReadBarcodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBarcode, true);
            app.ReadBarcodeButton.Text = 'Read Barcode';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RobotInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end