classdef RobotInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConnectButton          matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        SpeedEditFieldLabel    matlab.ui.control.Label
        SpeedEditField         matlab.ui.control.NumericEditField
        OutputEditFieldLabel   matlab.ui.control.Label
        OutputEditField        matlab.ui.control.EditField
        ConnectedLampLabel     matlab.ui.control.Label
        ConnectedLamp          matlab.ui.control.Lamp
        StopButton             matlab.ui.control.Button
        MotorRunningLampLabel  matlab.ui.control.Label
        MotorRunningLamp       matlab.ui.control.Lamp
        ReadBarcodeButton      matlab.ui.control.Button
        IdentifymarbleButton   matlab.ui.control.Button
        ResetBarcodeButton     matlab.ui.control.Button
    end


    properties (Access = private)
        lego
        motorGate
        motorBelt
        motorBar
        motorArm
        connection
        colorSensorBar
        colorSensorIdentify
        barcode = []
        typeMaterial
        typeCount
        blackBaseColor
        DEGREESPERCOLOR = 22.5
        MINCOLORCHANGE = 10
        currentArmPos
        appActive = true;
    end

    methods (Access = private)
        
        %determines correct position to move arm
        function [] = moveArm(app,currentMarbleType)
            
            want = false;
            
            %determines if current marble is one that we want
            for i = 1:length(app.typeMaterial)
                if currentMarbleType == app.typeMarble{i}
                    if app.typeCount(i) > 0
                        want = true;
                        app.typeCount(i) = app.typeCount(i) - 1;
                    end
                end
            end
            
            if want
                currentMarbleType = 'Want'
            end
            
            switch currentMarbleType
                case 'Want'
                    baseTurnDistance = 0;
                case 'Big Red'
                    baseTurnDistance = app.DEGREESPERCOLOR;
                case 'Small Red'
                    baseTurnDistance = app.DEGREESPERCOLOR;
                case 'Big Blue'
                    baseTurnDistance = 2*app.DEGREESPERCOLOR;
                case 'Small Blue'
                    baseTurnDistance = 2*app.DEGREESPERCOLOR;
                case 'Big White'
                    baseTurnDistance = 3*app.DEGREESPERCOLOR;
                case 'Small White'
                    baseTurnDistance = 3*app.DEGREESPERCOLOR;
                case 'Steel'
                    baseTurnDistance = 4*app.DEGREESPERCOLOR;
                case 'HDPE'
                    baseTurnDistance = 5*app.DEGREESPERCOLOR;
                case 'Big Yellow: Trash'
                    baseTurnDistance = 6*app.DEGREESPERCOLOR;
                case 'Small Yellow: Trash'
                    baseTurnDistance = 6*app.DEGREESPERCOLOR;
                case 'Big Green: Trash'
                    baseTurnDistance = 7*app.DEGREESPERCOLOR;
                case 'Small Green: Trash'
                    baseTurnDistance = 7*app.DEGREESPERCOLOR;
                case 'Unknown: Trash'
                    baseTurnDistance = 8*app.DEGREESPERCOLOR;
                otherwise
                    baseTurnDistance = app.currentArmPos;
            end
            
            turn = baseTurnDistance - app.currentArmPos;
            PositionController(app.motorArm,(-1)*turn);
            app.currentArmPos = readrotation(app.motorArm);
            
            GateFunction();
            
            app.motorBelt.Speed = app.SpeedEditField.Value;
            
            if app.appActive
                colorChange(app);
            end
        end
        %end function
        
        %detects color change
        function [] = colorChange(app)
            %ensures app is active before detecting
            while app.appActive
                
                rgb1 = readColorRGB(app.colorSensorIdentify);
                pause(0.5);
                rgb2 = readColorRGB(app.colorSensorIdentify);
                
                %looks for a color change
                if abs(rgb1(1) - rgb2(1)) > app.MINCOLORCHANGE || abs(rgb1(2) - rgb2(2)) > app.MINCOLORCHANGE || abs(rgb1(3) - rgb2(3)) > app.MINCOLORCHANGE
                    if app.appActive
                        app.motorBelt.Speed = 0;
                        idMarble(app);
                    end
                end
            end
        end
        %end function
        
        function [] = idMarble(app)
            N = 100
            cnt = 0;
            red = [];
            blue = [];
            green = [];
            while cnt < N
                %dont change these
                cnt = cnt+1;
                colors = readColorRGB(app.colorSensorIdentify);
                red = [red colors(1)-app.blackBaseColor(1)];
                green = [green colors(2)-app.blackBaseColor(2)];
                blue = [blue colors(3)-app.blackBaseColor(3)];
            end
                    
            rgb = [mean(red) mean(green) mean(blue)];
            currentMarbleType = marbleIdentifier(rgb);
            app.OutputEditField.Value = [currentMarbleType ' ' num2str(rgb)];
            
            if app.appActive
                moveArm(app,currentMarbleType);
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: ConnectButton
        function Connect(app, event)
           app.ConnectedLamp.Color = 'k';
           app.connection = false;
           success = 1;
           
           clearvars -global app.lego
           try
               app.lego = legoev3('usb');
               app.ConnectedLamp.Color = 'g';
               app.connection = true;
               
               app.motorBelt = motor(app.lego,'D');
               app.motorGate = motor(app.lego,'B');
               %app.motorBar = motor(app.lego,'C');
               app.motorArm = motor(app.lego,'A');
               
               resetRotation(app.motorBelt)
               resetRotation(app.motorGate)
               %resetRotation(app.motorBar)
               resetRotation(app.motorArm)
               
               %app.colorSensorBar = colorSensor(app.lego, 2);
               app.colorSensorIdentify = colorSensor(app.lego, 1);
               
               beep(app.lego);
               clearLCD(app.lego);
               app.ConnectButton.Enable = 'off';
           catch ME
               ME
               app.OutputEditField.Value = 'No Ev3 connected in USB port or motor port connections incorrect';
               success = 0;
           end
           
           if success
               app.blackBaseColor = readColorRGB(app.colorSensorIdentify);
           end
           
        end

        % Value changed function: SpeedEditField
        function OnChange(app, event)
            value = app.SpeedEditField.Value;
            if app.connection
                app.motorBelt.Speed = value;
            end
            app.OutputEditField.Value = ['Speed set to ' num2str(value)];
        end

        % Button pushed function: ReadBarcodeButton
        function ReadBarcode(app, event)
            if app.connection && any(find([app.lego.ConnectedSensors{:}] == 'color') == 1)
                
                app.colorSensorBar = colorSensor(app.lego, 1);
                
                app.barcode = BarcodeFunction(app.motorBar, app.colorSensorBar);
                
                barcodeString = num2str(app.barcode(1:3),'%d');
                
                switch barcodeString
                    %changed so that type count shares the its index with its type
                    case '001'
                        app.typeMaterial = [app.typeMaterial,'Big White', 'Small White'];
                    case '010'
                        app.typeMaterial = [app.typeMaterial,'Big Red', 'Small Red'];
                    case '011'
                        app.typeMaterial = [app.typeMaterial,'Big Blue', 'Small Blue'];
                    case '100'
                        app.typeMaterial = [app.typeMaterial,'Steel', 'HDPE'];
                    otherwise
                        app.OutputEditField.Value = 'invalid barcode';
                        app.typeMaterial = [app.typeMaterial,'Invalid', 'Invalid'];
                end
                
                if ~(any(ismember(app.typeMaterial, 'Invalid')))
                    decimalCount = polyval(app.barcode(4:8),2) - 1;
                    app.typeCount = [app.typeCount mod(decimalCount,4) floor(decimalCount/4)];
                else
                    app.typeCount = [app.typeCount 0 0];
                end
                app.OutputEditField.Value = WordJoiner(app.typeMaterial,app.typeCount);
                
            end
        end

        % Button pushed function: StartButton
        function Start(app, event)
            if app.connection
                app.MotorRunningLamp.Color = 'g';
                
                app.motorBelt.Speed = app.SpeedEditField.Value;
                
                pause(0.5);
                
                start(app.motorBelt);
            
                app.OutputEditField.Value = 'Started';
                
                if app.appActive
                    colorChange(app);
                end
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            if app.connection
                app.MotorRunningLamp.Color = 'k';
                
                app.motorBelt.Speed = 0;
                
                stop(app.motorBelt);
                
                app.OutputEditField.Value = 'Stopped';
                
                app.appActive = false;
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end

        % Button pushed function: IdentifymarbleButton
        function identifyMarble(app, event)
            %unused function, kept for legacy records
            if app.connection
                N = 100
                cnt = 0;
                red = [];
                blue = [];
                green = [];
                while cnt < N
                    %dont change these
                    cnt = cnt+1;
                    colors = readColorRGB(app.colorSensorIdentify);
                    red = [red colors(1)-app.blackBaseColor(1)];
                    green = [green colors(2)-app.blackBaseColor(2)];
                    blue = [blue colors(3)-app.blackBaseColor(3)];
                end
                    
                rgb = [mean(red) mean(green) mean(blue)];
                currentMarbleType = marbleIdentifier(rgb);
                app.OutputEditField.Value = [currentMarbleType num2str(rgb)];
                moveArm(app,currentMarbleType);
            else
                app.OutputEditField.Value = 'Ev3 not connected';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @Connect, true);
            app.ConnectButton.Text = 'Connect';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @OnChange, true);
            app.SpeedEditField.Limits = [0 40];
            app.SpeedEditField.ValueDisplayFormat = '%.0f';
            app.SpeedEditField.Value = 20;
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.Editable = 'off';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.MotorRunningLampLabel = uilabel(app.UIFigure);
            app.MotorRunningLampLabel.Text = 'Motor Running';
            app.MotorRunningLamp = uilamp(app.UIFigure);
            app.ReadBarcodeButton = uibutton(app.UIFigure, 'push');
            app.ReadBarcodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBarcode, true);
            app.ReadBarcodeButton.Text = 'Read Barcode';
            app.IdentifymarbleButton = uibutton(app.UIFigure, 'push');
            app.IdentifymarbleButton.ButtonPushedFcn = createCallbackFcn(app, @identifyMarble, true);
            app.IdentifymarbleButton.Text = 'Identify marble';
            app.ResetBarcodeButton = uibutton(app.UIFigure, 'push');
            app.ResetBarcodeButton.Text = 'Reset Barcode';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RobotInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end